classdef RGB_analysis_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        InstrukcjaButton            matlab.ui.control.Button
        LoadImageButton             matlab.ui.control.Button
        UIAxesOriginal              matlab.ui.control.UIAxes
        CenterPanel                 matlab.ui.container.Panel
        BackgroundThresholdSpinner  matlab.ui.control.Spinner
        BackgroundSpinnerLabel      matlab.ui.control.Label
        RemoveBackgroundCheckBox    matlab.ui.control.CheckBox
        UseMaskCheckBox             matlab.ui.control.CheckBox
        DeleteMaskButton            matlab.ui.control.StateButton
        SaveMaskButton              matlab.ui.control.Button
        MaskDropDown                matlab.ui.control.DropDown
        MaskaDropDownLabel          matlab.ui.control.Label
        ModeLabel                   matlab.ui.control.Label
        RelativeModeSwitch          matlab.ui.control.Switch
        WhiteThresholdSpinner       matlab.ui.control.Spinner
        PrgLabel                    matlab.ui.control.Label
        RemoveWhiteCheckBox         matlab.ui.control.CheckBox
        ContourSwitch               matlab.ui.control.Switch
        ObrysujkonturLabel_2        matlab.ui.control.Label
        ContourCountLabel           matlab.ui.control.Label
        AreaCountLabel              matlab.ui.control.Label
        AreaContorLabel             matlab.ui.control.Label
        AreaSwitch                  matlab.ui.control.Switch
        ObrysujkonturLabel          matlab.ui.control.Label
        ResetButton                 matlab.ui.control.Button
        BlueMaxSpinner              matlab.ui.control.Spinner
        MaxSpinner_3Label           matlab.ui.control.Label
        BlueMinSpinner              matlab.ui.control.Spinner
        MinSpinner_3Label           matlab.ui.control.Label
        SkadowefiltruRGBLabel       matlab.ui.control.Label
        GreenMaxSpinner             matlab.ui.control.Spinner
        MaxSpinner_2Label           matlab.ui.control.Label
        GreenMinSpinner             matlab.ui.control.Spinner
        MinSpinner_2Label           matlab.ui.control.Label
        RedMaxSpinner               matlab.ui.control.Spinner
        MaxSpinnerLabel             matlab.ui.control.Label
        RedMinSpinner               matlab.ui.control.Spinner
        MinSpinnerLabel             matlab.ui.control.Label
        BlueRangeSlider             matlab.ui.control.RangeSlider
        BlueLabel                   matlab.ui.control.Label
        GreenRangeSlider            matlab.ui.control.RangeSlider
        GreenLabel                  matlab.ui.control.Label
        RedRangeSlider              matlab.ui.control.RangeSlider
        RedLabel                    matlab.ui.control.Label
        RightPanel                  matlab.ui.container.Panel
        ExportButton                matlab.ui.control.Button
        SaveImageButton             matlab.ui.control.Button
        UIAxesProcessed             matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
         OriginalImage
         ProcessedImage
         FileName
         SavedMasks
         MasksFile = 'masks.mat'
    end
    
    methods (Access = private)
        
function ok = checkImageLoaded(app)

if isempty(app.OriginalImage)
    uialert(app.UIFigure, 'Najpierw wgraj zdjęcie!', 'Brak obrazu');
    ok = false;
else
    ok = true;
end

end

      function updateRGB(app)

if ~checkImageLoaded(app)
    return
end
          
if isempty(app.OriginalImage)
    return
end

img = app.OriginalImage;

R = img(:,:,1);
G = img(:,:,2);
B = img(:,:,3);

%Maska-suwaki
rRange = app.RedRangeSlider.Value;
gRange = app.GreenRangeSlider.Value;
bRange = app.BlueRangeSlider.Value;

mask = ...
    R >= rRange(1) & R <= rRange(2) & ...
    G >= gRange(1) & G <= gRange(2) & ...
    B >= bRange(1) & B <= bRange(2);

%Checkbox - dodaje zapisaną maskę
if app.UseMaskCheckBox.Value

    selectedMaskName = app.MaskDropDown.Value;

    if ~isempty(app.SavedMasks) && ~strcmp(selectedMaskName,'Brak masek')

        idx = find(strcmp({app.SavedMasks.Name}, selectedMaskName),1);
        savedMask = app.SavedMasks(idx);

        maskSaved = ...
            R >= savedMask.R(1) & R <= savedMask.R(2) & ...
            G >= savedMask.G(1) & G <= savedMask.G(2) & ...
            B >= savedMask.B(1) & B <= savedMask.B(2);

        mask = mask & maskSaved;

        % Biały
        removeWhite = app.RemoveWhiteCheckBox.Value || savedMask.RemoveWhite;

        thr = app.WhiteThresholdSpinner.Value;
        thr = max(thr, savedMask.WhiteThr);

    else
        removeWhite = app.RemoveWhiteCheckBox.Value;
        thr = app.WhiteThresholdSpinner.Value;
    end

else
    removeWhite = app.RemoveWhiteCheckBox.Value;
    thr = app.WhiteThresholdSpinner.Value;
end


result = img;
result(repmat(~mask,[1 1 3])) = 0;

%Usuń biały
if removeWhite
    whiteMask = R > thr & G > thr & B > thr;
    result(repmat(whiteMask,[1 1 3])) = 0;
end

% Usuwanie ciemnego tła
if app.RemoveBackgroundCheckBox.Value
    bgThr = app.BackgroundThresholdSpinner.Value;

    darkMask = R < bgThr;

    result(repmat(darkMask,[1 1 3])) = 0;
end

app.ProcessedImage = result;

imshow(result,'Parent',app.UIAxesProcessed)

updateAnalysis(app)

      end


    function originalPixels = getReferencePixels(app)

orig = app.OriginalImage;

if size(orig,3)==3
    gray = rgb2gray(orig);
else
    gray = orig;
end

bw = gray > 0;

mode = app.RelativeModeSwitch.Value;

R = orig(:,:,1);
G = orig(:,:,2);
B = orig(:,:,3);

thrWhite = app.WhiteThresholdSpinner.Value;
thrBg = app.BackgroundThresholdSpinner.Value;

if strcmp(mode,'No White/No Background')

    if app.RemoveWhiteCheckBox.Value
        whiteMask = R > thrWhite & G > thrWhite & B > thrWhite;
        bw(whiteMask) = 0;
    end

    if app.RemoveBackgroundCheckBox.Value
        darkMask = R < thrBg;
        bw(darkMask) = 0;
    end

end

originalPixels = sum(bw(:));

end


function drawResult(app)

img = app.ProcessedImage;

if size(img,3)==3
    gray = rgb2gray(img);
else
    gray = img;
end

bw = gray > 0;

showContour = strcmp(app.ContourSwitch.Value,'Tak');
showArea    = strcmp(app.AreaSwitch.Value,'Tak');

% Baza
overlay = zeros(size(img),'uint8');

% Area - liczę piksele
if showArea
    for c = 1:3
        temp = overlay(:,:,c);
        temp(bw) = 255;
        overlay(:,:,c) = temp;
    end
end

if showContour
    % Contour - licze to co we wnętrzu konturu
    [B, ~] = bwboundaries(bw, 'noholes');
    filled = false(size(bw));
    for k = 1:length(B)
        boundary = B{k};
        maskObj = poly2mask(boundary(:,2), boundary(:,1), size(bw,1), size(bw,2));
        filled = filled | maskObj;
    end

    
    perim = bwperim(bw);
    perimSmooth = imdilate(perim, strel('disk',1)); % pogrubienie konturu
    for c = 1:3
        temp = overlay(:,:,c);
        temp(perimSmooth) = 150;
        overlay(:,:,c) = temp;
    end
    filled = filled | perim;
end


if showArea || showContour
    imshow(overlay,'Parent',app.UIAxesProcessed)
else
    imshow(app.ProcessedImage,'Parent',app.UIAxesProcessed)
end

totalPixels = numel(bw);
originalPixels = getReferencePixels(app);
originalContourPixels = getReferenceContourPixels(app);

% Area
if showArea
    areaAbs = sum(bw(:)) / totalPixels * 100;
    areaRel = sum(bw(:)) / originalPixels * 100;
    app.AreaCountLabel.Text = sprintf('Abs: %.3f | Rel: %.3f', areaAbs, areaRel);
else
    app.AreaCountLabel.Text = '???%';
end

% Contour
if showContour
    contAbs = sum(filled(:)) / totalPixels * 100;
    contRel = sum(filled(:)) / originalContourPixels  * 100;
    app.ContourCountLabel.Text = sprintf('Abs: %.3f | Rel: %.3f', contAbs, contRel);
else
    app.ContourCountLabel.Text = '???%';
end

end

function originalContourPixels = getReferenceContourPixels(app)

orig = app.OriginalImage;

if size(orig,3)==3
    gray = rgb2gray(orig);
else
    gray = orig;
end

bw = gray > 0;

R = orig(:,:,1);
G = orig(:,:,2);
B = orig(:,:,3);

thrWhite = app.WhiteThresholdSpinner.Value;
thrBg = app.BackgroundThresholdSpinner.Value;

mode = app.RelativeModeSwitch.Value;

if strcmp(mode,'No White/No Background')

    if app.RemoveWhiteCheckBox.Value
        whiteMask = R > thrWhite & G > thrWhite & B > thrWhite;
        bw(whiteMask) = 0;
    end

    if app.RemoveBackgroundCheckBox.Value
        darkMask = R < thrBg;
        bw(darkMask) = 0;
    end

end


[B, ~] = bwboundaries(bw, 'noholes');
filled = false(size(bw));
perim = bwperim(bw);

for k = 1:length(B)
    boundary = B{k};
    maskObj = poly2mask(boundary(:,2), boundary(:,1), size(bw,1), size(bw,2));
    filled = filled | maskObj;
end

filled = filled | perim;
originalContourPixels = sum(filled(:));

end


function updateAnalysis(app)
    drawResult(app)
end


function updateMaskDropdown(app)

if isempty(app.SavedMasks)
    app.MaskDropDown.Items = {'Brak masek'};
else
    names = {app.SavedMasks.Name};
    app.MaskDropDown.Items = names;
end

end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxesOriginal.XTick = [];
            app.UIAxesOriginal.YTick = [];
            app.UIAxesProcessed.XTick = [];
            app.UIAxesProcessed.YTick = [];
if isfile(app.MasksFile)
    data = load(app.MasksFile);
    app.SavedMasks = data.SavedMasks;
else
    app.SavedMasks = struct( ...
    'Name', {}, ...
    'R', {}, ...
    'G', {}, ...
    'B', {}, ...
    'WhiteThr', {}, ...
    'RemoveWhite', {}, ...
    'BackThr', {}, ...
    'RemoveBack', {} );
end

updateMaskDropdown(app)

        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
[file,path] = uigetfile({'*.jpg;*.png;*.tif;*.tiff'});

if isequal(file,0)
    return
end

img = imread(fullfile(path,file));

% konwersja do uint8
if isa(img,'uint16')
    img = uint8(double(img) / 65535 * 255);
end

app.OriginalImage = img;
app.ProcessedImage = img;
app.FileName = file;

imshow(img,'Parent',app.UIAxesOriginal)
imshow(img,'Parent',app.UIAxesProcessed)
        end

        % Value changed function: RedRangeSlider
        function RedRangeSliderValueChanged(app, event)
val = app.RedRangeSlider.Value;

app.RedMinSpinner.Value = val(1);
app.RedMaxSpinner.Value = val(2);

updateRGB(app)

        end

        % Value changed function: GreenRangeSlider
        function GreenRangeSliderValueChanged(app, event)
val = app.GreenRangeSlider.Value;

app.GreenMinSpinner.Value = val(1);
app.GreenMaxSpinner.Value = val(2);

updateRGB(app)

        end

        % Value changed function: BlueRangeSlider
        function BlueRangeSliderValueChanged(app, event)
val = app.BlueRangeSlider.Value;

app.BlueMinSpinner.Value = val(1);
app.BlueMaxSpinner.Value = val(2);

updateRGB(app)

        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed(app, event)
 if ~checkImageLoaded(app)
    return
 end
 
r = app.RedRangeSlider.Value;
g = app.GreenRangeSlider.Value;
b = app.BlueRangeSlider.Value;
thr = app.WhiteThresholdSpinner.Value;
bgThr = app.BackgroundThresholdSpinner.Value;

selectedMask = app.MaskDropDown.Value;

if app.UseMaskCheckBox.Value && ~strcmp(selectedMask,'Brak masek')
    maskTag = ['_MASK-' selectedMask];
else
    maskTag = '';
end

[~,name,~] = fileparts(app.FileName);

newName = sprintf('%s%s_R%.0f-%.0f_G%.0f-%.0f_B%.0f-%.0f_W%d_B%d.png', ...
    name, maskTag, r(1),r(2), g(1),g(2), b(1),b(2), thr, bgThr);
[file,path] = uiputfile({'*.png';'*.jpg';'*.tif';'*.tiff'},'Zapisz jako', newName);

if isequal(file,0)
    return
end

imwrite(app.ProcessedImage, fullfile(path,file));


        end

        % Value changed function: ContourSwitch
        function ContourSwitchValueChanged(app, event)
          
updateRGB(app)
            
        end

        % Value changed function: AreaSwitch
        function AreaSwitchValueChanged(app, event)

updateRGB(app)

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
app.ProcessedImage = app.OriginalImage;

% suwaki RGB
app.RedRangeSlider.Value = [0 255];
app.GreenRangeSlider.Value = [0 255];
app.BlueRangeSlider.Value = [0 255];

% spinnery RGB
app.RedMinSpinner.Value = 0;
app.RedMaxSpinner.Value = 255;

app.GreenMinSpinner.Value = 0;
app.GreenMaxSpinner.Value = 255;

app.BlueMinSpinner.Value = 0;
app.BlueMaxSpinner.Value = 255;

% switche - analiza
app.ContourSwitch.Value = 'Nie';
app.AreaSwitch.Value = 'Nie';
app.RelativeModeSwitch.Value = 'Orginal';

% checkbox
app.RemoveWhiteCheckBox.Value = false;
app.WhiteThresholdSpinner.Value = 0;


app.RemoveBackgroundCheckBox.Value = false;
app.BackgroundThresholdSpinner.Value = 0;

% label - analiza
app.ContourCountLabel.Text = '???%';
app.AreaCountLabel.Text = '???%';

app.UseMaskCheckBox.Value = false;

imshow(app.OriginalImage,'Parent',app.UIAxesProcessed)

        end

        % Value changed function: RedMinSpinner
        function RedMinSpinnerValueChanged(app, event)
           
minVal = app.RedMinSpinner.Value;
maxVal = app.RedMaxSpinner.Value;

if minVal > maxVal
    minVal = maxVal;
    app.RedMinSpinner.Value = minVal;
end

app.RedRangeSlider.Value = [minVal maxVal];

updateRGB(app)

            
        end

        % Value changed function: RedMaxSpinner
        function RedMaxSpinnerValueChanged(app, event)

minVal = app.RedMinSpinner.Value;
maxVal = app.RedMaxSpinner.Value;

if maxVal < minVal
    maxVal = minVal;
    app.RedMaxSpinner.Value = maxVal;
end

app.RedRangeSlider.Value = [minVal maxVal];

updateRGB(app)
          
        end

        % Value changed function: GreenMinSpinner
        function GreenMinSpinnerValueChanged(app, event)
                      
minVal = app.GreenMinSpinner.Value;
maxVal = app.GreenMaxSpinner.Value;

if minVal > maxVal
    minVal = maxVal;
    app.GreenMinSpinner.Value = minVal;
end

app.GreenRangeSlider.Value = [minVal maxVal];

updateRGB(app)

        end

        % Value changed function: GreenMaxSpinner
        function GreenMaxSpinnerValueChanged(app, event)
minVal = app.GreenMinSpinner.Value;
maxVal = app.GreenMaxSpinner.Value;

if maxVal < minVal
    maxVal = minVal;
    app.GreenMaxSpinner.Value = maxVal;
end

app.GreenRangeSlider.Value = [minVal maxVal];

updateRGB(app)

        end

        % Value changed function: BlueMinSpinner
        function BlueMinSpinnerValueChanged(app, event)
                      
minVal = app.BlueMinSpinner.Value;
maxVal = app.BlueMaxSpinner.Value;

if minVal > maxVal
    minVal = maxVal;
    app.BlueMinSpinner.Value = minVal;
end

app.BlueRangeSlider.Value = [minVal maxVal];

updateRGB(app)
        end

        % Value changed function: BlueMaxSpinner
        function BlueMaxSpinnerValueChanged(app, event)
minVal = app.BlueMinSpinner.Value;
maxVal = app.BlueMaxSpinner.Value;

if maxVal < minVal
    maxVal = minVal;
    app.BlueMaxSpinner.Value = maxVal;
end

app.BlueRangeSlider.Value = [minVal maxVal];

updateRGB(app)

            
        end

        % Value changed function: RemoveWhiteCheckBox
        function RemoveWhiteCheckBoxValueChanged(app, event)
       
            updateRGB(app)    
       
        end

        % Value changed function: WhiteThresholdSpinner
        function WhiteThresholdSpinnerValueChanged(app, event)
            
            updateRGB(app)    
            
        end

        % Value changed function: RelativeModeSwitch
        function RelativeModeSwitchValueChanged(app, event)
            
            updateRGB(app)    
            
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
if ~checkImageLoaded(app)
    return
end

            
 % Nazwa pliku = nazwa obrazu
[~,name,~] = fileparts(app.FileName);

[file,path] = uiputfile('*.xlsx','Zapisz Excel', [name '.xlsx']);

if isequal(file,0)
    return
end

fullFile = fullfile(path,file);

% Nazwa arkusza
sheetName = name;
sheetName = sheetName(1:min(31,end)); % Excel limit

% Dane w arkuszu
r = app.RedRangeSlider.Value;
g = app.GreenRangeSlider.Value;
b = app.BlueRangeSlider.Value;
thr = app.WhiteThresholdSpinner.Value;
bgThr = app.BackgroundThresholdSpinner.Value;
mode = app.RelativeModeSwitch.Value;

selectedMask = app.MaskDropDown.Value;

if app.UseMaskCheckBox.Value && ~strcmp(selectedMask,'Brak masek')
    maskName = selectedMask;
else
    maskName = 'Brak';
end

if app.UseMaskCheckBox.Value && ~strcmp(app.MaskDropDown.Value,'Brak masek')
    idx = find(strcmp({app.SavedMasks.Name}, app.MaskDropDown.Value),1);
    m = app.SavedMasks(idx);

    maskParams = [m.R(1), m.R(2), m.G(1), m.G(2), m.B(1), m.B(2), m.WhiteThr, m.BackThr];
else
    maskParams = [NaN NaN NaN NaN NaN NaN NaN];
end

img = app.ProcessedImage;
gray = rgb2gray(img);
bw = gray > 0;
[B, ~] = bwboundaries(bw, 'noholes');
filled = false(size(bw));

for k = 1:length(B)
    boundary = B{k};
    maskObj = poly2mask(boundary(:,2), boundary(:,1), size(bw,1), size(bw,2));
    filled = filled | maskObj;
end

perim = bwperim(bw);
filled = filled | perim;

totalPixels = numel(bw);
originalPixels = getReferencePixels(app);
originalContourPixels = getReferenceContourPixels(app);

contAbs = sum(filled(:)) / totalPixels * 100;
contRel = sum(filled(:)) / originalContourPixels * 100;

areaAbs = sum(bw(:)) / totalPixels * 100;
areaRel = sum(bw(:)) / originalPixels * 100;

% Komentarz do danych
prompt = {'Komentarz do tego pomiaru:'};
dlgtitle = 'Komentarz';
answer = inputdlg(prompt,dlgtitle,[5 60]);

if isempty(answer)
    comment = '';
else
    comment = answer{1};
end

% Dane w excelu
row = {comment, maskName, r(1),r(2),g(1),g(2),b(1),b(2),thr, bgThr, mode, maskParams,contAbs,contRel,areaAbs,areaRel};


if isfile(fullFile)

    
    [~, sheets] = xlsfinfo(fullFile);

    if any(strcmp(sheets, sheetName))
        
        writecell(row, fullFile, 'Sheet', sheetName, 'WriteMode','append');
    else
        
        headers = {'Komentarz','Maska','Rmin','Rmax','Gmin','Gmax','Bmin','Bmax','White','Background','Mode','MaskRmin','MaskRmax','MaskGmin','MaskGmax','MaskBmin','MaskBmax','MaskWhite','MaskBackground','ContAbs','ContRel','AreaAbs','AreaRel'};
        
        writecell(headers, fullFile, 'Sheet', sheetName, 'Range','A1')
        writecell(row, fullFile, 'Sheet', sheetName, 'Range','A2')
    end

else
    headers = {'Komentarz','Maska','Rmin','Rmax','Gmin','Gmax','Bmin','Bmax','White','Background','Mode','MaskRmin','MaskRmax','MaskGmin','MaskGmax','MaskBmin','MaskBmax','MaskWhite','MaskBackground','ContAbs','ContRel','AreaAbs','AreaRel'};

    writecell(headers, fullFile, 'Sheet', sheetName, 'Range','A1')
    writecell(row, fullFile, 'Sheet', sheetName, 'Range','A2')
end


        end

        % Button pushed function: SaveMaskButton
        function SaveMaskButtonPushed(app, event)

prompt = {'Nazwa maski:'};
dlgtitle = 'Zapisz maskę';
answer = inputdlg(prompt, dlgtitle, [1 50]);

if isempty(answer)
    return
end

name = answer{1};

maskStruct.Name = name;
maskStruct.R = app.RedRangeSlider.Value;
maskStruct.G = app.GreenRangeSlider.Value;
maskStruct.B = app.BlueRangeSlider.Value;
maskStruct.WhiteThr = app.WhiteThresholdSpinner.Value;
maskStruct.RemoveWhite = app.RemoveWhiteCheckBox.Value;
maskStruct.BackThr = app.BackgroundThresholdSpinner.Value;
maskStruct.RemoveBack = app.RemoveBackgroundCheckBox.Value;


if isempty(app.SavedMasks)
    app.SavedMasks = maskStruct;
else
    app.SavedMasks(end+1) = maskStruct;
end

SavedMasks = app.SavedMasks;

save(app.MasksFile, 'SavedMasks')

updateMaskDropdown(app)

        end

        % Value changed function: DeleteMaskButton
        function DeleteMaskButtonValueChanged(app, event)

name = app.MaskDropDown.Value;

if strcmp(name,'Brak masek')
    return
end

idx = find(strcmp({app.SavedMasks.Name}, name),1);
app.SavedMasks(idx) = [];

SavedMasks = app.SavedMasks;
save(app.MasksFile, 'SavedMasks')

updateMaskDropdown(app)

        end

        % Value changed function: UseMaskCheckBox
        function UseMaskCheckBoxValueChanged(app, event)
            updateRGB(app)
        end

        % Value changed function: BackgroundThresholdSpinner
        function BackgroundThresholdSpinnerValueChanged(app, event)
          updateRGB(app)
            
        end

        % Value changed function: RemoveBackgroundCheckBox
        function RemoveBackgroundCheckBoxValueChanged(app, event)
       updateRGB(app)
            
        end

        % Button pushed function: InstrukcjaButton
        function InstrukcjaButtonPushed(app, event)
            fileName = 'RGB_analysis_App_Instrukcja.txt';

if ~isfile(fileName)
    uialert(app.UIFigure, 'Nie znaleziono pliku instrukcji!', 'Błąd');
    return
end

txt = fileread(fileName);

fig = uifigure('Name','Instrukcja','Position',[100 100 600 500]);


uitextarea(fig,...
    'Value', splitlines(txt), ...
    'Editable','off', ...
    'Position',[10 10 580 480]);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {513, 513, 513};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {513, 513};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {373, '1x', 382};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxesOriginal = uiaxes(app.LeftPanel);
            zlabel(app.UIAxesOriginal, 'Z')
            app.LoadImageButton = uibutton(app.LeftPanel, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Wgraj zdjęcie';
            app.InstrukcjaButton = uibutton(app.LeftPanel, 'push');
            app.InstrukcjaButton.ButtonPushedFcn = createCallbackFcn(app, @InstrukcjaButtonPushed, true);
            app.InstrukcjaButton.Text = 'Instrukcja';
            app.CenterPanel = uipanel(app.GridLayout);
            app.RedLabel = uilabel(app.CenterPanel);
            app.RedLabel.Text = 'Red';
            app.RedRangeSlider = uislider(app.CenterPanel, 'range');
            app.RedRangeSlider.Limits = [0 255];
            app.RedRangeSlider.ValueChangedFcn = createCallbackFcn(app, @RedRangeSliderValueChanged, true);
            app.RedRangeSlider.Value = [0 255];
            app.GreenLabel = uilabel(app.CenterPanel);
            app.GreenLabel.Text = 'Green';
            app.GreenRangeSlider = uislider(app.CenterPanel, 'range');
            app.GreenRangeSlider.Limits = [0 255];
            app.GreenRangeSlider.ValueChangedFcn = createCallbackFcn(app, @GreenRangeSliderValueChanged, true);
            app.GreenRangeSlider.Value = [0 255];
            app.BlueLabel = uilabel(app.CenterPanel);
            app.BlueLabel.Text = 'Blue';
            app.BlueRangeSlider = uislider(app.CenterPanel, 'range');
            app.BlueRangeSlider.Limits = [0 255];
            app.BlueRangeSlider.ValueChangedFcn = createCallbackFcn(app, @BlueRangeSliderValueChanged, true);
            app.BlueRangeSlider.Value = [0 255];
            app.MinSpinnerLabel = uilabel(app.CenterPanel);
            app.MinSpinnerLabel.Text = 'Min';
            app.RedMinSpinner = uispinner(app.CenterPanel);
            app.RedMinSpinner.Limits = [0 255];
            app.RedMinSpinner.ValueChangedFcn = createCallbackFcn(app, @RedMinSpinnerValueChanged, true);
            app.MaxSpinnerLabel = uilabel(app.CenterPanel);
            app.MaxSpinnerLabel.Text = 'Max';
            app.RedMaxSpinner = uispinner(app.CenterPanel);
            app.RedMaxSpinner.Limits = [0 255];
            app.RedMaxSpinner.ValueChangedFcn = createCallbackFcn(app, @RedMaxSpinnerValueChanged, true);
            app.RedMaxSpinner.Value = 255;
            app.MinSpinner_2Label = uilabel(app.CenterPanel);
            app.MinSpinner_2Label.Text = 'Min';
            app.GreenMinSpinner = uispinner(app.CenterPanel);
            app.GreenMinSpinner.Limits = [0 255];
            app.GreenMinSpinner.ValueChangedFcn = createCallbackFcn(app, @GreenMinSpinnerValueChanged, true);
            app.MaxSpinner_2Label = uilabel(app.CenterPanel);
            app.MaxSpinner_2Label.Text = 'Max';
            app.GreenMaxSpinner = uispinner(app.CenterPanel);
            app.GreenMaxSpinner.Limits = [0 255];
            app.GreenMaxSpinner.ValueChangedFcn = createCallbackFcn(app, @GreenMaxSpinnerValueChanged, true);
            app.GreenMaxSpinner.Value = 255;
            app.SkadowefiltruRGBLabel = uilabel(app.CenterPanel);
            app.SkadowefiltruRGBLabel.Text = 'Składowe filtru RGB';
            app.MinSpinner_3Label = uilabel(app.CenterPanel);
            app.MinSpinner_3Label.Text = 'Min';
            app.BlueMinSpinner = uispinner(app.CenterPanel);
            app.BlueMinSpinner.Limits = [0 255];
            app.BlueMinSpinner.ValueChangedFcn = createCallbackFcn(app, @BlueMinSpinnerValueChanged, true);
            app.MaxSpinner_3Label = uilabel(app.CenterPanel);
            app.MaxSpinner_3Label.Text = 'Max';
            app.BlueMaxSpinner = uispinner(app.CenterPanel);
            app.BlueMaxSpinner.Limits = [0 255];
            app.BlueMaxSpinner.ValueChangedFcn = createCallbackFcn(app, @BlueMaxSpinnerValueChanged, true);
            app.BlueMaxSpinner.Value = 255;
            app.ResetButton = uibutton(app.CenterPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Resetuj';
            app.ObrysujkonturLabel = uilabel(app.CenterPanel);
            app.ObrysujkonturLabel.Text = 'Zaznacz powierzchnię';
            app.AreaSwitch = uiswitch(app.CenterPanel, 'slider');
            app.AreaSwitch.Items = {'Nie', 'Tak'};
            app.AreaSwitch.ValueChangedFcn = createCallbackFcn(app, @AreaSwitchValueChanged, true);
            app.AreaSwitch.Value = 'Nie';
            app.AreaContorLabel = uilabel(app.CenterPanel);
            app.AreaContorLabel.Text = 'Procentowa ilość powierzchni zdjecia:';
            app.AreaCountLabel = uilabel(app.CenterPanel);
            app.AreaCountLabel.Text = '???%';
            app.ContourCountLabel = uilabel(app.CenterPanel);
            app.ContourCountLabel.Text = '???%';
            app.ObrysujkonturLabel_2 = uilabel(app.CenterPanel);
            app.ObrysujkonturLabel_2.Text = 'Obrysuj kontur';
            app.ContourSwitch = uiswitch(app.CenterPanel, 'slider');
            app.ContourSwitch.Items = {'Nie', 'Tak'};
            app.ContourSwitch.ValueChangedFcn = createCallbackFcn(app, @ContourSwitchValueChanged, true);
            app.ContourSwitch.Value = 'Nie';
            app.RemoveWhiteCheckBox = uicheckbox(app.CenterPanel);
            app.RemoveWhiteCheckBox.ValueChangedFcn = createCallbackFcn(app, @RemoveWhiteCheckBoxValueChanged, true);
            app.RemoveWhiteCheckBox.Text = 'Usuń biały kolor';
            app.PrgLabel = uilabel(app.CenterPanel);
            app.PrgLabel.Text = 'White';
            app.WhiteThresholdSpinner = uispinner(app.CenterPanel);
            app.WhiteThresholdSpinner.Limits = [0 255];
            app.WhiteThresholdSpinner.ValueChangedFcn = createCallbackFcn(app, @WhiteThresholdSpinnerValueChanged, true);
            app.RelativeModeSwitch = uiswitch(app.CenterPanel, 'slider');
            app.RelativeModeSwitch.Items = {'Orginal', 'No White/No Background'};
            app.RelativeModeSwitch.ValueChangedFcn = createCallbackFcn(app, @RelativeModeSwitchValueChanged, true);
            app.RelativeModeSwitch.Value = 'Orginal';
            app.ModeLabel = uilabel(app.CenterPanel);
            app.ModeLabel.Text = 'Mode:';
            app.MaskaDropDownLabel = uilabel(app.CenterPanel);
            app.MaskaDropDownLabel.Text = 'Maska';
            app.MaskDropDown = uidropdown(app.CenterPanel);
            app.SaveMaskButton = uibutton(app.CenterPanel, 'push');
            app.SaveMaskButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMaskButtonPushed, true);
            app.SaveMaskButton.Text = 'Zapisz maskę';
            app.DeleteMaskButton = uibutton(app.CenterPanel, 'state');
            app.DeleteMaskButton.ValueChangedFcn = createCallbackFcn(app, @DeleteMaskButtonValueChanged, true);
            app.DeleteMaskButton.Text = 'Usuń maskę';
            app.UseMaskCheckBox = uicheckbox(app.CenterPanel);
            app.UseMaskCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseMaskCheckBoxValueChanged, true);
            app.UseMaskCheckBox.Text = 'Użyj maski';
            app.RemoveBackgroundCheckBox = uicheckbox(app.CenterPanel);
            app.RemoveBackgroundCheckBox.ValueChangedFcn = createCallbackFcn(app, @RemoveBackgroundCheckBoxValueChanged, true);
            app.RemoveBackgroundCheckBox.Text = 'Usuń tło';
            app.BackgroundSpinnerLabel = uilabel(app.CenterPanel);
            app.BackgroundSpinnerLabel.Text = 'Background';
            app.BackgroundThresholdSpinner = uispinner(app.CenterPanel);
            app.BackgroundThresholdSpinner.Limits = [0 255];
            app.BackgroundThresholdSpinner.ValueChangedFcn = createCallbackFcn(app, @BackgroundThresholdSpinnerValueChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxesProcessed = uiaxes(app.RightPanel);
            title(app.UIAxesProcessed, ' ')
            zlabel(app.UIAxesProcessed, 'Z')
            app.SaveImageButton = uibutton(app.RightPanel, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Text = 'Zapisz zdjęcie';
            app.ExportButton = uibutton(app.RightPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Eksportuj do MS EXcel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RGB_analysis_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end